classdef BMP180 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ANAEKRANTab                 matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        TextArea                    matlab.ui.control.TextArea
        YukseklikEditFieldLabel     matlab.ui.control.Label
        YukseklikEditField          matlab.ui.control.NumericEditField
        TextArea_2                  matlab.ui.control.TextArea
        BasincEditFieldLabel        matlab.ui.control.Label
        BasincEditField             matlab.ui.control.NumericEditField
        SistemlerDropDownLabel      matlab.ui.control.Label
        SistemlerDropDown           matlab.ui.control.DropDown
        HizEditFieldLabel           matlab.ui.control.Label
        HizEditField                matlab.ui.control.NumericEditField
        BASLAButton                 matlab.ui.control.Button
        Lamp2Label                  matlab.ui.control.Label
        Lamp2                       matlab.ui.control.Lamp
        XAcisiEditFieldLabel        matlab.ui.control.Label
        XAcisiEditField             matlab.ui.control.NumericEditField
        YatayUzaklikEditFieldLabel  matlab.ui.control.Label
        YatayUzaklikEditField       matlab.ui.control.NumericEditField
        YAcisiEditFieldLabel        matlab.ui.control.Label
        YAcisiEditField             matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        AnaBilgisayarTab            matlab.ui.container.Tab
        Image_2                     matlab.ui.control.Image
        AYRINTILIVERILERTextArea    matlab.ui.control.TextArea
        ADXL345GYROEditFieldLabel   matlab.ui.control.Label
        ADXL345GYROEditField        matlab.ui.control.NumericEditField
        BMP280BASINCEditFieldLabel  matlab.ui.control.Label
        BMP280BASINCEditField       matlab.ui.control.NumericEditField
        NEO6MGPSEditFieldLabel      matlab.ui.control.Label
        NEO6MGPSEditField           matlab.ui.control.NumericEditField
        Image2                      matlab.ui.control.Image
        TextArea_4                  matlab.ui.control.TextArea
        YedekBilgisayarTab          matlab.ui.container.Tab
        Image_3                     matlab.ui.control.Image
        Image2_2                    matlab.ui.control.Image
        TextArea_5                  matlab.ui.control.TextArea
        MPU6050GYROEditFieldLabel   matlab.ui.control.Label
        MPU6050GYROEditField        matlab.ui.control.NumericEditField
        NEO6MGPSEditField_2Label    matlab.ui.control.Label
        NEO6MGPSEditField_2         matlab.ui.control.NumericEditField
        AYRINTILIVERILERTextArea_2  matlab.ui.control.TextArea
        FaydaliYukTab               matlab.ui.container.Tab
        Image_4                     matlab.ui.control.Image
        Image2_3                    matlab.ui.control.Image
        TextArea_6                  matlab.ui.control.TextArea
        MPU9250GYROEditFieldLabel   matlab.ui.control.Label
        MPU9250GYROEditField        matlab.ui.control.NumericEditField
        NEO6MGPSEditField_3Label    matlab.ui.control.Label
        NEO6MGPSEditField_3         matlab.ui.control.NumericEditField
        AYRINTILIVERILERTextArea_3  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BASLAButton
        function BASLAButtonPushed(app, event)
            clc
clear all;
clear all;
clf

   c=importdata('ucus_veri.txt');
   figure
   plot(c(1:938,1),c(1:938,2)); %YÜKSEKLİK
   figure
   plot(c(1:938,1),c(1:938,3)); %HIZ
   figure
   plot(c(1:938,1),c(1:938,4)); %BASINÇ
   figure
   

%%%%%%%%%%%%%% obje oluşturma kısmı bitti şimdi arduinodan veri alışı var


% a = arduino('COM4', 'Uno', 'Libraries', 'I2C');
%  imu = mpu6050(a,'SampleRate',50,'SamplesPerRead',5,'ReadMode','Latest');
%      gyroReadings = readAngularVelocity(imu);
d=5;


for d=1:1000000

% m.data(d:d+1)

   a=importdata('denm.txt');

time=1:length((a(:,1)));
   %plot(app.UIAxes,time(length(a)),a(length(a))); %YÜKSEKLİK

    plot(app.UIAxes2,time,a(:,3)); %HIZ

    plot(app.UIAxes2_2,time,a(:,2)); %BASINÇ

    drawnow;
  %  pause(.05);
  %%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%% GPS
%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN

% a=37.0866;
% 
% b=29.35225;
% %transmitter
% tx = txsite('Name','MathWorks Apple Hill',...
%        'Latitude',a, ...
%        'Longitude',b);
%    %show(tx);
%    
%  %receiver
%    rx = rxsite('Name','MathWorks Lakeside', ...
%        'Latitude',37.0821, ...
%        'Longitude',29.3564);
%   
%    %distance
%    dm = distance(tx,rx); % Unit: m
%    dkm = dm / 1000; %unit:km
%    azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
%    azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
%    
%    ss = sigstrength(rx,tx);
%    margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
%    link(rx,tx)
 %%  coverage(tx,'close-in', ...
    %%   'SignalStrengths',-100:5:-60) %sinyl gücüneg göre kapsadığı alan
   %%%%%5
   %%%%%%%%%%%%%%%%%%%%%%
   %%%%%%%%%%%%%%%%%%%%5 HIZ GRAFİĞİ ve diğer GRAFİKLER


end


%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN

% a=37.0866;
% 
% b=29.35225;
% %transmitter
% tx = txsite('Name','MathWorks Apple Hill',...
%        'Latitude',a, ...
%        'Longitude',b);
%    %show(tx);
%    
%  %receiver
%    rx = rxsite('Name','MathWorks Lakeside', ...
%        'Latitude',37.3021, ...
%        'Longitude',29.3764);
%   
%    %distance
%    dm = distance(tx,rx); % Unit: m
%    dkm = dm / 1000; %unit:km
%    azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
%    azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
%    
%    ss = sigstrength(rx,tx);
%    margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
%    link(rx,tx)
%   % coverage(tx,'close-in', ...
%    %    'SignalStrengths',-100:5:-60) %sinyl gücüneg göre kapsadığı alan

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ANAEKRANTab = uitab(app.TabGroup);
            app.ANAEKRANTab.Title = 'ANA EKRAN';
            app.Image = uiimage(app.ANAEKRANTab);
            app.Image.ImageSource = 'pp.jpg';
            app.TextArea = uitextarea(app.ANAEKRANTab);
            app.TextArea.Value = {'YER İSTASYONU'};
            app.YukseklikEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YukseklikEditFieldLabel.Text = 'Yukseklik';
            app.YukseklikEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.TextArea_2 = uitextarea(app.ANAEKRANTab);
            app.TextArea_2.Value = {'ROKET VERİLERİ'};
            app.BasincEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.BasincEditFieldLabel.Text = 'Basinc';
            app.BasincEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.SistemlerDropDownLabel = uilabel(app.ANAEKRANTab);
            app.SistemlerDropDownLabel.Text = 'Sistemler';
            app.SistemlerDropDown = uidropdown(app.ANAEKRANTab);
            app.SistemlerDropDown.Items = {'Ana Bilgisayar', 'Yedek Bilgisayar', 'Faydali Yuk'};
            app.SistemlerDropDown.Value = 'Ana Bilgisayar';
            app.HizEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.HizEditFieldLabel.Text = 'Hiz';
            app.HizEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.BASLAButton = uibutton(app.ANAEKRANTab, 'push');
            app.BASLAButton.ButtonPushedFcn = createCallbackFcn(app, @BASLAButtonPushed, true);
            app.BASLAButton.Text = 'BASLA';
            app.Lamp2Label = uilabel(app.ANAEKRANTab);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.ANAEKRANTab);
            app.XAcisiEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.XAcisiEditFieldLabel.Text = 'X Acisi';
            app.XAcisiEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YatayUzaklikEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YatayUzaklikEditFieldLabel.Text = 'Yatay Uzaklik';
            app.YatayUzaklikEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YAcisiEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YAcisiEditFieldLabel.Text = 'Y Acisi';
            app.YAcisiEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.UIAxes = uiaxes(app.ANAEKRANTab);
            title(app.UIAxes, 'Yükseklik vs Zaman')
            xlabel(app.UIAxes, 'Zaman(s)')
            ylabel(app.UIAxes, 'Yükseklik(m)')
            app.UIAxes2 = uiaxes(app.ANAEKRANTab);
            title(app.UIAxes2, 'Hız vs Zaman')
            xlabel(app.UIAxes2, 'Zaman')
            ylabel(app.UIAxes2, 'Hiz(m/s)')
            app.UIAxes2_2 = uiaxes(app.ANAEKRANTab);
            title(app.UIAxes2_2, 'Hız vs Zaman')
            xlabel(app.UIAxes2_2, 'Zaman')
            ylabel(app.UIAxes2_2, 'Basınc')
            app.AnaBilgisayarTab = uitab(app.TabGroup);
            app.AnaBilgisayarTab.Title = 'Ana Bilgisayar';
            app.Image_2 = uiimage(app.AnaBilgisayarTab);
            app.Image_2.ImageSource = 'pp.jpg';
            app.AYRINTILIVERILERTextArea = uitextarea(app.AnaBilgisayarTab);
            app.AYRINTILIVERILERTextArea.Value = {'AYRINTILI VERILER'};
            app.ADXL345GYROEditFieldLabel = uilabel(app.AnaBilgisayarTab);
            app.ADXL345GYROEditFieldLabel.Text = {'ADXL345'; '(GYRO)'};
            app.ADXL345GYROEditField = uieditfield(app.AnaBilgisayarTab, 'numeric');
            app.BMP280BASINCEditFieldLabel = uilabel(app.AnaBilgisayarTab);
            app.BMP280BASINCEditFieldLabel.Text = {'BMP280'; '(BASINC)'};
            app.BMP280BASINCEditField = uieditfield(app.AnaBilgisayarTab, 'numeric');
            app.NEO6MGPSEditFieldLabel = uilabel(app.AnaBilgisayarTab);
            app.NEO6MGPSEditFieldLabel.Text = {'NEO-6M'; '(GPS)'};
            app.NEO6MGPSEditField = uieditfield(app.AnaBilgisayarTab, 'numeric');
            app.Image2 = uiimage(app.AnaBilgisayarTab);
            app.Image2.ImageSource = 'Screenshot_2.png';
            app.TextArea_4 = uitextarea(app.AnaBilgisayarTab);
            app.TextArea_4.Value = {'KONUM'};
            app.YedekBilgisayarTab = uitab(app.TabGroup);
            app.YedekBilgisayarTab.Title = 'Yedek Bilgisayar';
            app.Image_3 = uiimage(app.YedekBilgisayarTab);
            app.Image_3.ImageSource = 'pp.jpg';
            app.Image2_2 = uiimage(app.YedekBilgisayarTab);
            app.Image2_2.ImageSource = 'Screenshot_2.png';
            app.TextArea_5 = uitextarea(app.YedekBilgisayarTab);
            app.TextArea_5.Value = {'KONUM'};
            app.MPU6050GYROEditFieldLabel = uilabel(app.YedekBilgisayarTab);
            app.MPU6050GYROEditFieldLabel.Text = {'MPU6050'; '(GYRO)'};
            app.MPU6050GYROEditField = uieditfield(app.YedekBilgisayarTab, 'numeric');
            app.NEO6MGPSEditField_2Label = uilabel(app.YedekBilgisayarTab);
            app.NEO6MGPSEditField_2Label.Text = {'NEO-6M'; '(GPS)'};
            app.NEO6MGPSEditField_2 = uieditfield(app.YedekBilgisayarTab, 'numeric');
            app.AYRINTILIVERILERTextArea_2 = uitextarea(app.YedekBilgisayarTab);
            app.AYRINTILIVERILERTextArea_2.Value = {'AYRINTILI VERILER'};
            app.FaydaliYukTab = uitab(app.TabGroup);
            app.FaydaliYukTab.Title = 'Faydali Yuk';
            app.Image_4 = uiimage(app.FaydaliYukTab);
            app.Image_4.ImageSource = 'pp.jpg';
            app.Image2_3 = uiimage(app.FaydaliYukTab);
            app.Image2_3.ImageSource = 'Screenshot_2.png';
            app.TextArea_6 = uitextarea(app.FaydaliYukTab);
            app.TextArea_6.Value = {'KONUM'};
            app.MPU9250GYROEditFieldLabel = uilabel(app.FaydaliYukTab);
            app.MPU9250GYROEditFieldLabel.Text = {'MPU9250'; '(GYRO)'};
            app.MPU9250GYROEditField = uieditfield(app.FaydaliYukTab, 'numeric');
            app.NEO6MGPSEditField_3Label = uilabel(app.FaydaliYukTab);
            app.NEO6MGPSEditField_3Label.Text = {'NEO-6M'; '(GPS)'};
            app.NEO6MGPSEditField_3 = uieditfield(app.FaydaliYukTab, 'numeric');
            app.AYRINTILIVERILERTextArea_3 = uitextarea(app.FaydaliYukTab);
            app.AYRINTILIVERILERTextArea_3.Value = {'AYRINTILI VERILER'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMP180

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end