classdef GPS_deneme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ANAEKRANTab                 matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        TextArea                    matlab.ui.control.TextArea
        YukseklikEditFieldLabel     matlab.ui.control.Label
        YukseklikEditField          matlab.ui.control.NumericEditField
        TextArea_2                  matlab.ui.control.TextArea
        BasincEditFieldLabel        matlab.ui.control.Label
        BasincEditField             matlab.ui.control.NumericEditField
        SistemlerDropDownLabel      matlab.ui.control.Label
        SistemlerDropDown           matlab.ui.control.DropDown
        HizEditFieldLabel           matlab.ui.control.Label
        HizEditField                matlab.ui.control.NumericEditField
        BASLAButton                 matlab.ui.control.Button
        Lamp2Label                  matlab.ui.control.Label
        Lamp2                       matlab.ui.control.Lamp
        XAcisiEditFieldLabel        matlab.ui.control.Label
        XAcisiEditField             matlab.ui.control.NumericEditField
        YatayUzaklikEditFieldLabel  matlab.ui.control.Label
        YatayUzaklikEditField       matlab.ui.control.NumericEditField
        YAcisiEditFieldLabel        matlab.ui.control.Label
        YAcisiEditField             matlab.ui.control.NumericEditField
        TextArea_3                  matlab.ui.control.TextArea
        LatitudeEditFieldLabel      matlab.ui.control.Label
        LatitudeEditField           matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel     matlab.ui.control.Label
        LongitudeEditField          matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BASLAButton
        function BASLAButtonPushed(app, event)
            clc
    
 app.LatitudeEditField.Value=double(37.07702); 
app.LongitudeEditField.Value=29.34456; 

%    c=importdata('ucus_veri.txt');
%    figure
%    plot(c(1:938,1),c(1:938,2)); %YÜKSEKLİK
%    figure
%    plot(c(1:938,1),c(1:938,3)); %HIZ
%    figure
%    plot(c(1:938,1),c(1:938,4)); %BASINÇ
%    figure
   

%%%%%%%%%%%%%% obje oluşturma kısmı bitti şimdi arduinodan veri alışı var


% a = arduino('COM4', 'Uno', 'Libraries', 'I2C');
%  imu = mpu6050(a,'SampleRate',50,'SamplesPerRead',5,'ReadMode','Latest');
%      gyroReadings = readAngularVelocity(imu);
d=5;


% for d=1:1000000
% 
% % m.data(d:d+1)
% 
%    a=importdata('denm.txt');
% 
% time=1:length((a(:,1)));
%    %plot(app.UIAxes,time(length(a)),a(length(a))); %YÜKSEKLİK
% 
%     plot(app.UIAxes,time,a(:,4)); %yükseklik
% 
%     
% 
% 
%     plot(app.UIAxes2_2,time,a(:,1)); %BASINÇ
% 
clc
clear
clear all


% a=37.0866;
% 
% b=29.35225;
% %transmitter
% tx = txsite('Name','MathWorks Apple Hill',...
%        'Latitude',a, ...
%        'Longitude',b,...
%         'TransmitterFrequency', 2.4e9, ...
%         'TransmitterPower', 18);
%    %show(tx);
%    
 %receiver
   rx = rxsite('Name','MathWorks Lakeside', ...
        'Latitude',37.1920547, ...
       'Longitude',29.3775272);
  
show(rx)


%    %distance
%    dm = distance(tx,rx); % Unit: m
%    dkm = dm / 1000; %unit:km
%    azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
%    azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
%    
%    ss = sigstrength(rx,tx);
%    margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
%    link(rx)
% %   figure
%  %  coverage(tx,rx)
%    %%coverage(tx,'close-in', ...
%      %%  'SignalStrengths',-100:5:-60) %sinyl gücüneg göre kapsadığı alan


% end


%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN

% a=37.0866;
% 
% b=29.35225;
% %transmitter
% tx = txsite('Name','MathWorks Apple Hill',...
%        'Latitude',a, ...
%        'Longitude',b);
%    %show(tx);
%    
%  %receiver
%    rx = rxsite('Name','MathWorks Lakeside', ...
%        'Latitude',37.3021, ...
%        'Longitude',29.3764);
%   
%    %distance
%    dm = distance(tx,rx); % Unit: m
%    dkm = dm / 1000; %unit:km
%    azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
%    azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
%    
%    ss = sigstrength(rx,tx);
%    margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
%    link(rx,tx)
%   % coverage(tx,'close-in', ...
%    %    'SignalStrengths',-100:5:-60) %sinyl gücüneg göre kapsadığı alan

        end

        % Value changed function: YukseklikEditField
        function YukseklikEditFieldValueChanged(app, event)
            value = app.YukseklikEditField.Value;
            
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)

            
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ANAEKRANTab = uitab(app.TabGroup);
            app.ANAEKRANTab.Title = 'ANA EKRAN';
            app.Image = uiimage(app.ANAEKRANTab);
            app.Image.ImageSource = 'pp.jpg';
            app.TextArea = uitextarea(app.ANAEKRANTab);
            app.TextArea.Value = {'YER İSTASYONU'};
            app.YukseklikEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YukseklikEditFieldLabel.Text = 'Yukseklik';
            app.YukseklikEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YukseklikEditField.ValueChangedFcn = createCallbackFcn(app, @YukseklikEditFieldValueChanged, true);
            app.TextArea_2 = uitextarea(app.ANAEKRANTab);
            app.TextArea_2.Value = {'ROKET VERİLERİ'};
            app.BasincEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.BasincEditFieldLabel.Text = 'Basinc';
            app.BasincEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.SistemlerDropDownLabel = uilabel(app.ANAEKRANTab);
            app.SistemlerDropDownLabel.Text = 'Sistemler';
            app.SistemlerDropDown = uidropdown(app.ANAEKRANTab);
            app.SistemlerDropDown.Items = {'Ana Bilgisayar', 'Yedek Bilgisayar', 'Faydali Yuk'};
            app.SistemlerDropDown.Value = 'Ana Bilgisayar';
            app.HizEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.HizEditFieldLabel.Text = 'Hiz';
            app.HizEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.BASLAButton = uibutton(app.ANAEKRANTab, 'push');
            app.BASLAButton.ButtonPushedFcn = createCallbackFcn(app, @BASLAButtonPushed, true);
            app.BASLAButton.Text = 'BASLA';
            app.Lamp2Label = uilabel(app.ANAEKRANTab);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.ANAEKRANTab);
            app.XAcisiEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.XAcisiEditFieldLabel.Text = 'X Acisi';
            app.XAcisiEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YatayUzaklikEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YatayUzaklikEditFieldLabel.Text = 'Yatay Uzaklik';
            app.YatayUzaklikEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YAcisiEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YAcisiEditFieldLabel.Text = 'Y Acisi';
            app.YAcisiEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.TextArea_3 = uitextarea(app.ANAEKRANTab);
            app.TextArea_3.Value = {'KONUM'};
            app.LatitudeEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.LatitudeEditFieldLabel.Text = ' Latitude';
            app.LatitudeEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.LongitudeEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPS_deneme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end