classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ANAEKRANTab            matlab.ui.container.Tab
        LampLabel              matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        BMP280EditFieldLabel   matlab.ui.control.Label
        BMP280EditField        matlab.ui.control.EditField
        STARTButton            matlab.ui.control.Button
        StopButton             matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        MPU6050EditFieldLabel  matlab.ui.control.Label
        MPU6050EditField       matlab.ui.control.NumericEditField
        BLMELERDropDownLabel   matlab.ui.control.Label
        BLMELERDropDown        matlab.ui.control.DropDown
        GPSEditFieldLabel      matlab.ui.control.Label
        GPSEditField           matlab.ui.control.EditField
        TextArea_2             matlab.ui.control.TextArea
        MPU6050Tab             matlab.ui.container.Tab
        VERLEREditFieldLabel   matlab.ui.control.Label
        VERLEREditField        matlab.ui.control.EditField
        GPSTab                 matlab.ui.container.Tab
        EditFieldLabel         matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        BMP280Tab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

a = arduino('COM3','Uno');

for i=1:20
writeDigitalPin(a,'D7',1);
app.Lamp.Color =[0.00,0.00,1.00];
pause(1);
writeDigitalPin(a,'D7',0);
app.Lamp.Color =[1.00,1.00,1.00];
pause(1)
end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
clear a
 a = arduino('COM3','Uno');
for i=1:20
 writeDigitalPin(a,'D7',0);
 app.Lamp.Color =[1.00,1.00,1.00];
 pause(1);
 writeDigitalPin(a,'D7',0);
 app.Lamp.Color =[1.00,1.00,1.00];
 pause(1)
 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ANAEKRANTab = uitab(app.TabGroup);
            app.ANAEKRANTab.Title = 'ANA EKRAN';
            app.LampLabel = uilabel(app.ANAEKRANTab);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.ANAEKRANTab);
            app.BMP280EditFieldLabel = uilabel(app.ANAEKRANTab);
            app.BMP280EditFieldLabel.Text = 'BMP280';
            app.BMP280EditField = uieditfield(app.ANAEKRANTab, 'text');
            app.STARTButton = uibutton(app.ANAEKRANTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.StopButton = uibutton(app.ANAEKRANTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextArea = uitextarea(app.ANAEKRANTab);
            app.TextArea.Value = {'KAYZER ROKET TAKIMI'};
            app.MPU6050EditFieldLabel = uilabel(app.ANAEKRANTab);
            app.MPU6050EditFieldLabel.Text = 'MPU6050';
            app.MPU6050EditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.BLMELERDropDownLabel = uilabel(app.ANAEKRANTab);
            app.BLMELERDropDownLabel.Text = 'BÖLMELER';
            app.BLMELERDropDown = uidropdown(app.ANAEKRANTab);
            app.BLMELERDropDown.Items = {'ANA BİLGİSAYAR', 'YARDIMCI BİLGİSAYAR', 'FAYDALI YÜK'};
            app.BLMELERDropDown.Value = 'FAYDALI YÜK';
            app.GPSEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.GPSEditFieldLabel.Text = 'GPS';
            app.GPSEditField = uieditfield(app.ANAEKRANTab, 'text');
            app.TextArea_2 = uitextarea(app.ANAEKRANTab);
            app.TextArea_2.Value = {'YER İSTASYONU'};
            app.MPU6050Tab = uitab(app.TabGroup);
            app.MPU6050Tab.Title = 'MPU6050';
            app.VERLEREditFieldLabel = uilabel(app.MPU6050Tab);
            app.VERLEREditFieldLabel.Text = 'VERİLER';
            app.VERLEREditField = uieditfield(app.MPU6050Tab, 'text');
            app.GPSTab = uitab(app.TabGroup);
            app.GPSTab.Title = 'GPS';
            app.EditFieldLabel = uilabel(app.GPSTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GPSTab, 'text');
            app.BMP280Tab = uitab(app.TabGroup);
            app.BMP280Tab.Title = 'BMP280';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end