classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EmreEditFieldLabel       matlab.ui.control.Label
        EmreEditField            matlab.ui.control.NumericEditField
        MuhammetEditFieldLabel   matlab.ui.control.Label
        MuhammetEditField        matlab.ui.control.NumericEditField
        VeliEditFieldLabel       matlab.ui.control.Label
        VeliEditField            matlab.ui.control.NumericEditField
        YakupEditFieldLabel      matlab.ui.control.Label
        YakupEditField           matlab.ui.control.NumericEditField
        CicekyurtEditFieldLabel  matlab.ui.control.Label
        CicekyurtEditField       matlab.ui.control.NumericEditField
        AKTIFLampLabel           matlab.ui.control.Label
        AKTIFLamp                matlab.ui.control.Lamp
        OyunDropDownLabel        matlab.ui.control.Label
        OyunDropDown             matlab.ui.control.DropDown
        RunButton                matlab.ui.control.Button
        SayEditFieldLabel        matlab.ui.control.Label
        SayEditField             matlab.ui.control.NumericEditField
        BirinciEditFieldLabel    matlab.ui.control.Label
        BirinciEditField         matlab.ui.control.EditField
        ikiEditFieldLabel        matlab.ui.control.Label
        ikiEditField             matlab.ui.control.EditField
        ucEditFieldLabel         matlab.ui.control.Label
        ucEditField              matlab.ui.control.EditField
        dortEditFieldLabel       matlab.ui.control.Label
        dortEditField            matlab.ui.control.EditField
        besEditFieldLabel        matlab.ui.control.Label
        besEditField             matlab.ui.control.EditField
        EYTANINIZBOLOLSUNLabel   matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        SIRALAMALabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RunButton_2Pushed(app, event)
           say = app.SayEditField.Value;
            for i=1:say
            emre(i)=app.EmreEditField.Value;
            muhammet(i)=app.MuhammetEditField.Value;
            veli(i)=app.VeliEditField.Value;
            yakup(i)=app.YakupEditField.Value;
            cicekyurt(i)=app.CicekyurtEditField.Value;
           
            end 
            skor=[sum(emre),sum(muhammet),sum(veli), sum(yakup),sum(cicekyurt)]
            minum=find(min(skor));
            if skor(minum)==skor(1);
             app.BirinciEditField.Value='emre';    
            end
            
            
        end

        % Value changed function: EmreEditField
        function EmreEditFieldValueChanged(app, event)
            value = app.EmreEditField.Value;
            
        end

        % Value changed function: SayEditField
        function SayEditFieldValueChanged(app, event)
            say = app.SayEditField.Value;
            
        end

        % Callback function
        function SralamaListBoxValueChanged(app, event)
            bir = app.SralamaListBox.Value;
            
        end

        % Value changed function: BirinciEditField
        function BirinciEditFieldValueChanged(app, event)
            value = app.BirinciEditField.Value;
            
        end

        % Value changing function: BirinciEditField
        function BirinciEditFieldValueChanging(app, event)
            birinci = event.Value;
            
        end

        % Value changed function: ikiEditField
        function ikiEditFieldValueChanged(app, event)
            value = app.ikiEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EmreEditFieldLabel = uilabel(app.UIFigure);
            app.EmreEditFieldLabel.Text = 'Emre';
            app.EmreEditField = uieditfield(app.UIFigure, 'numeric');
            app.EmreEditField.ValueChangedFcn = createCallbackFcn(app, @EmreEditFieldValueChanged, true);
            app.MuhammetEditFieldLabel = uilabel(app.UIFigure);
            app.MuhammetEditFieldLabel.Text = 'Muhammet';
            app.MuhammetEditField = uieditfield(app.UIFigure, 'numeric');
            app.VeliEditFieldLabel = uilabel(app.UIFigure);
            app.VeliEditFieldLabel.Text = 'Veli';
            app.VeliEditField = uieditfield(app.UIFigure, 'numeric');
            app.YakupEditFieldLabel = uilabel(app.UIFigure);
            app.YakupEditFieldLabel.Text = 'Yakup';
            app.YakupEditField = uieditfield(app.UIFigure, 'numeric');
            app.CicekyurtEditFieldLabel = uilabel(app.UIFigure);
            app.CicekyurtEditFieldLabel.Text = 'Cicekyurt';
            app.CicekyurtEditField = uieditfield(app.UIFigure, 'numeric');
            app.AKTIFLampLabel = uilabel(app.UIFigure);
            app.AKTIFLampLabel.Text = 'AKTIF';
            app.AKTIFLamp = uilamp(app.UIFigure);
            app.OyunDropDownLabel = uilabel(app.UIFigure);
            app.OyunDropDownLabel.Text = 'Oyun';
            app.OyunDropDown = uidropdown(app.UIFigure);
            app.OyunDropDown.Items = {'Pis Yedili', 'Batak'};
            app.OyunDropDown.Value = 'Pis Yedili';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.SayEditFieldLabel = uilabel(app.UIFigure);
            app.SayEditFieldLabel.Text = 'Sayı';
            app.SayEditField = uieditfield(app.UIFigure, 'numeric');
            app.SayEditField.ValueChangedFcn = createCallbackFcn(app, @SayEditFieldValueChanged, true);
            app.BirinciEditFieldLabel = uilabel(app.UIFigure);
            app.BirinciEditFieldLabel.Text = 'Birinci';
            app.BirinciEditField = uieditfield(app.UIFigure, 'text');
            app.BirinciEditField.ValueChangedFcn = createCallbackFcn(app, @BirinciEditFieldValueChanged, true);
            app.BirinciEditField.ValueChangingFcn = createCallbackFcn(app, @BirinciEditFieldValueChanging, true);
            app.ikiEditFieldLabel = uilabel(app.UIFigure);
            app.ikiEditFieldLabel.Text = 'iki';
            app.ikiEditField = uieditfield(app.UIFigure, 'text');
            app.ikiEditField.ValueChangedFcn = createCallbackFcn(app, @ikiEditFieldValueChanged, true);
            app.ucEditFieldLabel = uilabel(app.UIFigure);
            app.ucEditFieldLabel.Text = 'uc';
            app.ucEditField = uieditfield(app.UIFigure, 'text');
            app.dortEditFieldLabel = uilabel(app.UIFigure);
            app.dortEditFieldLabel.Text = 'dort';
            app.dortEditField = uieditfield(app.UIFigure, 'text');
            app.besEditFieldLabel = uilabel(app.UIFigure);
            app.besEditFieldLabel.Text = 'bes';
            app.besEditField = uieditfield(app.UIFigure, 'text');
            app.EYTANINIZBOLOLSUNLabel = uilabel(app.UIFigure);
            app.EYTANINIZBOLOLSUNLabel.Text = 'ŞEYTANINIZ BOL OLSUN!!!';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'images.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'imagen-pis-yedili-0thumb.jpeg';
            app.SIRALAMALabel = uilabel(app.UIFigure);
            app.SIRALAMALabel.Text = 'SIRALAMA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end