classdef algoritma < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ANAEKRANTab                 matlab.ui.container.Tab
        Image4                      matlab.ui.control.Image
        DURDUREditField             matlab.ui.control.NumericEditField
        DURDUREditFieldLabel        matlab.ui.control.Label
        YAcisiEditField             matlab.ui.control.NumericEditField
        YAcisiEditFieldLabel        matlab.ui.control.Label
        XAcisiEditField             matlab.ui.control.NumericEditField
        XAcisiEditFieldLabel        matlab.ui.control.Label
        BASLAButton                 matlab.ui.control.Button
        BasincEditField             matlab.ui.control.NumericEditField
        BasincEditFieldLabel        matlab.ui.control.Label
        TextArea_2                  matlab.ui.control.TextArea
        YukseklikEditField          matlab.ui.control.NumericEditField
        YukseklikEditFieldLabel     matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        BasnTab                     matlab.ui.container.Tab
        DUREditField                matlab.ui.control.NumericEditField
        DUREditFieldLabel           matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        YkseklikEditField           matlab.ui.control.NumericEditField
        YkseklikEditFieldLabel      matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        BMP280BASINCEditField       matlab.ui.control.NumericEditField
        BMP280BASINCEditFieldLabel  matlab.ui.control.Label
        AYRINTILIVERILERTextArea    matlab.ui.control.TextArea
        Image_2                     matlab.ui.control.Image
        UIAxes2_4                   matlab.ui.control.UIAxes
        UIAxes2_3                   matlab.ui.control.UIAxes
        GpsTab                      matlab.ui.container.Tab
        StopButton_2                matlab.ui.control.Button
        DurEditField_2              matlab.ui.control.NumericEditField
        DurEditField_2Label         matlab.ui.control.Label
        StartButton_3               matlab.ui.control.Button
        BoylamEditField             matlab.ui.control.NumericEditField
        BoylamEditFieldLabel        matlab.ui.control.Label
        AYRINTILIVERILERTextArea_2  matlab.ui.control.TextArea
        EnlemEditField              matlab.ui.control.NumericEditField
        EnlemEditFieldLabel         matlab.ui.control.Label
        Image2_2                    matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        GYROTab                     matlab.ui.container.Tab
        StopButton_3                matlab.ui.control.Button
        DurEditField                matlab.ui.control.NumericEditField
        DurEditFieldLabel           matlab.ui.control.Label
        StartButton_2               matlab.ui.control.Button
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        AYRINTILIVERILERTextArea_3  matlab.ui.control.TextArea
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        Image_4                     matlab.ui.control.Image
        UIAxes2_7                   matlab.ui.control.UIAxes
        UIAxes2_6                   matlab.ui.control.UIAxes
        UIAxes2_5                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BASLAButton
        function BASLAButtonPushed(app, event)
            clc

clf
a=38.7256;

b=35.4846;
%transmitter
tx = txsite('Name','MathWorks Apple Hill',...
       'Latitude',a, ...
       'Longitude',b);
   %show(tx);
   
 %receiver
   rx = rxsite('Name','MathWorks Lakeside', ...
       'Latitude',38.7240, ...
       'Longitude',35.4810);
  
   %distance
   dm = distance(tx,rx); % Unit: m
   dkm = dm / 1000; %unit:km
   azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
   azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
   
   ss = sigstrength(rx,tx);
   margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
   link(rx,tx)
   coverage(tx,'close-in', ...
      'SignalStrengths',-70:1:-45) %sinyl gücüneg göre kapsadığı alan
%    %%%%5
   %%%%%%%%%%%%%%%%%%%%%
   %%%%%%%%%%%%%%%%%%%5 HIZ GRAFİĞİ ve diğer GRAFİKLER
%    c=importdata('ucus_veri.txt');
%    figure
%    plot(c(1:938,1),c(1:938,2)); %YÜKSEKLİK
%    figure
%    plot(c(1:938,1),c(1:938,3)); %HIZ
%    figure
%    plot(c(1:938,1),c(1:938,4)); %BASINÇ
%    figure
   
ax = axes('XLim',[-4 4],'YLim',[-4 4],'ZLim',[-4 4]);
view(3); grid on; axis equal;
 %axis off;
% Create objects to group
[cylx,cyly,cylz]=cylinder(.4);
[conx,cony,conz]=cylinder([1,0]);
[mx,my,mz]=cylinder([0,0.1]);
[x,y,z]=sphere(50);
h(1)=surface(x,y,z-1);
h(2)=surface(cylx,cyly,cylz*2+1); %orta gövde
h(3)=surface(conx/2-0.01,cony/2-0.01,conz+3); %baş kısımı
h(4)=surface(my/2,mx/4+0.4,mz/1.5+0.5,'FaceColor','red');
h(5)=surface(my/2,mx/4-0.4,mz/1.5+0.5,'FaceColor','red');
h(6)=surface(my/2+0.4,mx/4,mz/1.5+0.5,'FaceColor','red');
h(7)=surface(my/2-0.4,mx/4,mz/1.5+0.5,'FaceColor','red');
set(h(2:7),'Clipping','off');
t = hgtransform('Parent',ax);
set(h(2:7),'Parent',t);
drawnow;
k=0;
%%%%%%%%%%%%%% obje oluşturma kısmı bitti şimdi arduinodan veri alışı var

radians_degree = pi/180;
% a = arduino('COM4', 'Uno', 'Libraries', 'I2C');
%  imu = mpu6050(a,'SampleRate',50,'SamplesPerRead',5,'ReadMode','Latest');
%      gyroReadings = readAngularVelocity(imu);
d=5;


for d=1:10000000
a=importdata('deneme.txt');
% y_veri=importdata('y.txt');
% m=[x_veri,y_veri];
%     m=importdata('denm.txt');
% m.data(d:d+1)
%     gyroReadings= [m.data((length(m.data)-1));m.data((length(m.data)))];

if app.DURDUREditField.Value==1
    break
end

    a0=a(end-1,1);   %xaxis reading
    a1=a(end-1,2);
    k=(a0-90);%yaxis reading
     k0=(a0-90);         %shifting to 0
     k1=(a1-90);
    r0 = k0*radians_degree;
    r1=k1*radians_degree;
   
    Rz = makehgtform('xrotate',r0,'yrotate',r1);
    set(t,'Matrix',Rz)
    drawnow;

%      createfigure(ZData1, YData1, XData1, ZData2, YData2, XData2, ...
%          ZData3, YData3, XData3, ZData4, YData4, XData4, 
%      YData5, YData6, XData5, XData6)
  %  pause(.05);
  %%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%% GPS
%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN
time=1:length((a(end-50:end-1,1)));
   %plot(app.UIAxes,time(length(a)),a(length(a))); %YÜKSEKLİK

    plot(app.UIAxes2,time,a(end-50:end-1,1));%x ekseni


     plot(app.UIAxes2_2,time,a(end-50:end-1,2)); %y ekseni

drawnow;
    

 app.YukseklikEditField.Value=a(end-1,8);
 app.BasincEditField.Value=a(end-1,4);
 app.XAcisiEditField.Value=a(end-1,1);
 app.YAcisiEditField.Value=a(end-1,2);

end


%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN

% a=37.0866;
% 
% b=29.35225;
% %transmitter
% tx = txsite('Name','MathWorks Apple Hill',...
%        'Latitude',a, ...
%        'Longitude',b);
%    %show(tx);
%    
%  %receiver
%    rx = rxsite('Name','MathWorks Lakeside', ...
%        'Latitude',37.3021, ...
%        'Longitude',29.3764);
%   
%    %distance
%    dm = distance(tx,rx); % Unit: m
%    dkm = dm / 1000; %unit:km
%    azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
%    azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
%    
%    ss = sigstrength(rx,tx);
%    margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
%    link(rx,tx)
%   % coverage(tx,'close-in', ...
%    %    'SignalStrengths',-100:5:-60) %sinyl gücüneg göre kapsadığı alan

        end

        % Value changed function: DURDUREditField
        function DURDUREditFieldValueChanged(app, event)
            value = app.DURDUREditField.Value;
            
        end

        % Value changed function: YukseklikEditField
        function YukseklikEditFieldValueChanged(app, event)
            value = app.YukseklikEditField.Value;
            
        end

        % Value changed function: BasincEditField
        function BasincEditFieldValueChanged(app, event)
            value = app.BasincEditField.Value;
            
        end

        % Value changed function: XAcisiEditField
        function XAcisiEditFieldValueChanged(app, event)
            value = app.XAcisiEditField.Value;
            
        end

        % Value changed function: YAcisiEditField
        function YAcisiEditFieldValueChanged(app, event)
            value = app.YAcisiEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            for d=1:10000000
a=importdata('deneme.txt');
% y_veri=importdata('y.txt');
% m=[x_veri,y_veri];
%     m=importdata('denm.txt');
% m.data(d:d+1)
%     gyroReadings= [m.data((length(m.data)-1));m.data((length(m.data)))];

if app.DURDUREditField.Value==1
    break
end

%     a0=a(end-1,1);   %xaxis reading
%     a1=a(end-1,2);
%     k=(a0-90);%yaxis reading
%      k0=(a0-90);         %shifting to 0
%      k1=(a1-90);
%     r0 = k0*radians_degree;
%     r1=k1*radians_degree;
%    
%     Rz = makehgtform('xrotate',r0,'yrotate',r1);
%     set(t,'Matrix',Rz)
    drawnow;

%      createfigure(ZData1, YData1, XData1, ZData2, YData2, XData2, ...
%          ZData3, YData3, XData3, ZData4, YData4, XData4, 
%      YData5, YData6, XData5, XData6)
  %  pause(.05);
  %%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%% GPS
%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN
time=1:length((a(end-50:end-1,1)));
   %plot(app.UIAxes,time(length(a)),a(length(a))); %YÜKSEKLİK


     plot(app.UIAxes2_3,time,-a(end-50:end-1,4)); %y ekseni
      plot(app.UIAxes2_4,time,-a(end-50:end-1,8)); %y ekseni
    app.YkseklikEditField.Value=a(end-1,8);
app.BMP280BASINCEditField.Value=a(end-1,4);
drawnow;

if app.DUREditField.Value==1
    break
end


end
        end

        % Value changed function: BMP280BASINCEditField
        function BMP280BASINCEditFieldValueChanged(app, event)
            value = app.BMP280BASINCEditField.Value;
            
        end

        % Button down function: UIAxes2_3
        function UIAxes2_3ButtonDown(app, event)
            
        end

        % Value changed function: YkseklikEditField
        function YkseklikEditFieldValueChanged(app, event)
            value = app.YkseklikEditField.Value;
            
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
                        for d=1:10000000
a=importdata('deneme.txt');
% y_veri=importdata('y.txt');
% m=[x_veri,y_veri];
%     m=importdata('denm.txt');
% m.data(d:d+1)
%     gyroReadings= [m.data((length(m.data)-1));m.data((length(m.data)))];

if app.DURDUREditField.Value==1
    break
end

%     a0=a(end-1,1);   %xaxis reading
%     a1=a(end-1,2);
%     k=(a0-90);%yaxis reading
%      k0=(a0-90);         %shifting to 0
%      k1=(a1-90);
%     r0 = k0*radians_degree;
%     r1=k1*radians_degree;
%    
%     Rz = makehgtform('xrotate',r0,'yrotate',r1);
%     set(t,'Matrix',Rz)
    drawnow;

%      createfigure(ZData1, YData1, XData1, ZData2, YData2, XData2, ...
%          ZData3, YData3, XData3, ZData4, YData4, XData4, 
%      YData5, YData6, XData5, XData6)
  %  pause(.05);
  %%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%% GPS
%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN
time=1:length((a(end-50:end-1,1)));
   %plot(app.UIAxes,time(length(a)),a(length(a))); %YÜKSEKLİK


     plot(app.UIAxes2_5,time,-a(end-50:end-1,1)); %y ekseni
      plot(app.UIAxes2_6,time,-a(end-50:end-1,2)); %y ekseni
      plot(app.UIAxes2_7,time,-a(end-50:end-1,3)); %z ekseni
    app.XEditField.Value=a(end-1,1);
app.YEditField.Value=a(end-1,2);
app.ZEditField.Value=a(end-1,3);
drawnow;
if app.DurEditField.Value==1
    break
end


end

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            
        end

        % Value changed function: DurEditField
        function DurEditFieldValueChanged(app, event)
            value = app.DurEditField.Value;
            
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
              clc

clf
a=38.7256;

b=35.4846;
%transmitter
tx = txsite('Name','MathWorks Apple Hill',...
       'Latitude',a, ...
       'Longitude',b);
   %show(tx);
   
 %receiver
   rx = rxsite('Name','MathWorks Lakeside', ...
       'Latitude',38.7240, ...
       'Longitude',35.4810);
  
   %distance
   dm = distance(tx,rx); % Unit: m
   dkm = dm / 1000; %unit:km
   azFromEast = angle(tx,rx); % Unit: degrees counter-clockwise from East
   azFromNorth = -azFromEast + 90; % Convert angle to clockwise from North
   
   ss = sigstrength(rx,tx);
   margin = abs(rx.ReceiverSensitivity - ss); %hassassiyeti çıkararak ölçer
   link(rx,tx)
   coverage(tx,'close-in', ...
      'SignalStrengths',-70:1:-45) %sinyl gücüneg göre kapsadığı alan
   %%%%5
   %%%%%%%%%%%%%%%%%%%%%
   %%%%%%%%%%%%%%%%%%%5 HIZ GRAFİĞİ ve diğer GRAFİKLER
%    c=importdata('ucus_veri.txt');
%    figure
%    plot(c(1:938,1),c(1:938,2)); %YÜKSEKLİK
%    figure
%    plot(c(1:938,1),c(1:938,3)); %HIZ
%    figure
%    plot(c(1:938,1),c(1:938,4)); %BASINÇ
%    figure
   
% ax = axes('XLim',[-4 4],'YLim',[-4 4],'ZLim',[-4 4]);
% view(3); grid on; axis equal;
%  %axis off;
% % Create objects to group
% [cylx,cyly,cylz]=cylinder(.4);
% [conx,cony,conz]=cylinder([1,0]);
% [mx,my,mz]=cylinder([0,0.1]);
% [x,y,z]=sphere(50);
% h(1)=surface(x,y,z-1);
% h(2)=surface(cylx,cyly,cylz*2+1); %orta gövde
% h(3)=surface(conx/2-0.01,cony/2-0.01,conz+3); %baş kısımı
% h(4)=surface(my/2,mx/4+0.4,mz/1.5+0.5,'FaceColor','red');
% h(5)=surface(my/2,mx/4-0.4,mz/1.5+0.5,'FaceColor','red');
% h(6)=surface(my/2+0.4,mx/4,mz/1.5+0.5,'FaceColor','red');
% h(7)=surface(my/2-0.4,mx/4,mz/1.5+0.5,'FaceColor','red');
% set(h(2:7),'Clipping','off');
% t = hgtransform('Parent',ax);
% set(h(2:7),'Parent',t);
% drawnow;
% k=0;
% %%%%%%%%%%%%%% obje oluşturma kısmı bitti şimdi arduinodan veri alışı var
% 
% radians_degree = pi/180;
% % a = arduino('COM4', 'Uno', 'Libraries', 'I2C');
% %  imu = mpu6050(a,'SampleRate',50,'SamplesPerRead',5,'ReadMode','Latest');
% %      gyroReadings = readAngularVelocity(imu);
% d=5;


for d=1:10000000
a=importdata('deneme.txt');
% y_veri=importdata('y.txt');
% m=[x_veri,y_veri];
%     m=importdata('denm.txt');
% m.data(d:d+1)
%     gyroReadings= [m.data((length(m.data)-1));m.data((length(m.data)))];

if app.DurEditField_2.Value==1
    break
end
% 
%     a0=a(end-1,1);   %xaxis reading
%     a1=a(end-1,2);
%     k=(a0-90);%yaxis reading
%      k0=(a0-90);         %shifting to 0
%      k1=(a1-90);
%     r0 = k0*radians_degree;
%     r1=k1*radians_degree;
%    
%     Rz = makehgtform('xrotate',r0,'yrotate',r1);
%     set(t,'Matrix',Rz)
%     drawnow;

%      createfigure(ZData1, YData1, XData1, ZData2, YData2, XData2, ...
%          ZData3, YData3, XData3, ZData4, YData4, XData4, 
%      YData5, YData6, XData5, XData6)
  %  pause(.05);
  %%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%5
  %%%%%%%%%%%%%%%%%%%%%%%%%%% GPS
%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%% GPS İÇİN
% time=1:length((a(end-50:end-1,1)));
%    %plot(app.UIAxes,time(length(a)),a(length(a))); %YÜKSEKLİK
% 
%     plot(app.UIAxes2,time,a(end-50:end-1,1));%x ekseni
% 
% 
%      plot(app.UIAxes2_2,time,a(end-50:end-1,2)); %y ekseni
% 
% drawnow;
    

app.EnlemEditField.Value=38.7256;
app.BoylamEditField.Value= 35.4846;


end
        end

        % Value changed function: EnlemEditField
        function EnlemEditFieldValueChanged(app, event)
            value = app.EnlemEditField.Value;
            
        end

        % Value changed function: BoylamEditField
        function BoylamEditFieldValueChanged(app, event)
            value = app.BoylamEditField.Value;
            
        end

        % Value changed function: DurEditField_2
        function DurEditField_2ValueChanged(app, event)
            value = app.DurEditField_2.Value;
            
        end

        % Value changed function: DUREditField
        function DUREditFieldValueChanged(app, event)
            value = app.DUREditField.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.DUREditField.Value=1;
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.DurEditField_2.Value=1;
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            app.DurEditField.Value=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ANAEKRANTab = uitab(app.TabGroup);
            app.ANAEKRANTab.Title = 'ANA EKRAN';
            app.UIAxes2 = uiaxes(app.ANAEKRANTab);
            title(app.UIAxes2, 'Hız vs Zaman')
            xlabel(app.UIAxes2, 'Zaman')
            ylabel(app.UIAxes2, 'X ekseni')
            app.UIAxes2_2 = uiaxes(app.ANAEKRANTab);
            title(app.UIAxes2_2, 'Hız vs Zaman')
            xlabel(app.UIAxes2_2, 'Zaman')
            ylabel(app.UIAxes2_2, 'Y Ekseni')
            app.UIAxes2_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TextArea = uitextarea(app.ANAEKRANTab);
            app.TextArea.Value = {'YER İSTASYONU'};
            app.YukseklikEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YukseklikEditFieldLabel.Text = 'Yukseklik';
            app.YukseklikEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YukseklikEditField.ValueChangedFcn = createCallbackFcn(app, @YukseklikEditFieldValueChanged, true);
            app.TextArea_2 = uitextarea(app.ANAEKRANTab);
            app.TextArea_2.Value = {'ROKET VERİLERİ'};
            app.BasincEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.BasincEditFieldLabel.Text = 'Basinc';
            app.BasincEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.BasincEditField.ValueChangedFcn = createCallbackFcn(app, @BasincEditFieldValueChanged, true);
            app.BASLAButton = uibutton(app.ANAEKRANTab, 'push');
            app.BASLAButton.ButtonPushedFcn = createCallbackFcn(app, @BASLAButtonPushed, true);
            app.BASLAButton.Text = 'BASLA';
            app.XAcisiEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.XAcisiEditFieldLabel.Text = 'X Acisi';
            app.XAcisiEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.XAcisiEditField.ValueChangedFcn = createCallbackFcn(app, @XAcisiEditFieldValueChanged, true);
            app.YAcisiEditFieldLabel = uilabel(app.ANAEKRANTab);
            app.YAcisiEditFieldLabel.Text = 'Y Acisi';
            app.YAcisiEditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.YAcisiEditField.ValueChangedFcn = createCallbackFcn(app, @YAcisiEditFieldValueChanged, true);
            app.DURDUREditFieldLabel = uilabel(app.ANAEKRANTab);
            app.DURDUREditFieldLabel.Text = 'DURDUR';
            app.DURDUREditField = uieditfield(app.ANAEKRANTab, 'numeric');
            app.DURDUREditField.ValueChangedFcn = createCallbackFcn(app, @DURDUREditFieldValueChanged, true);
            app.Image4 = uiimage(app.ANAEKRANTab);
            app.Image4.ImageSource = 'rrr.png';
            app.BasnTab = uitab(app.TabGroup);
            app.BasnTab.Title = 'Basınç';
            app.UIAxes2_3 = uiaxes(app.BasnTab);
            title(app.UIAxes2_3, 'Hız vs Zaman')
            xlabel(app.UIAxes2_3, 'Zaman')
            ylabel(app.UIAxes2_3, 'Basınç')
            app.UIAxes2_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes2_3ButtonDown, true);
            app.UIAxes2_4 = uiaxes(app.BasnTab);
            title(app.UIAxes2_4, 'Hız vs Zaman')
            xlabel(app.UIAxes2_4, 'Zaman')
            ylabel(app.UIAxes2_4, 'Yükseklik')
            app.Image_2 = uiimage(app.BasnTab);
            app.Image_2.ImageSource = 'rrr.png';
            app.AYRINTILIVERILERTextArea = uitextarea(app.BasnTab);
            app.AYRINTILIVERILERTextArea.Value = {'AYRINTILI VERILER'};
            app.BMP280BASINCEditFieldLabel = uilabel(app.BasnTab);
            app.BMP280BASINCEditFieldLabel.Text = {'BMP280'; '(BASINC)'};
            app.BMP280BASINCEditField = uieditfield(app.BasnTab, 'numeric');
            app.BMP280BASINCEditField.ValueChangedFcn = createCallbackFcn(app, @BMP280BASINCEditFieldValueChanged, true);
            app.StartButton = uibutton(app.BasnTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.YkseklikEditFieldLabel = uilabel(app.BasnTab);
            app.YkseklikEditFieldLabel.Text = 'Yükseklik';
            app.YkseklikEditField = uieditfield(app.BasnTab, 'numeric');
            app.YkseklikEditField.ValueChangedFcn = createCallbackFcn(app, @YkseklikEditFieldValueChanged, true);
            app.StopButton = uibutton(app.BasnTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DUREditFieldLabel = uilabel(app.BasnTab);
            app.DUREditFieldLabel.Text = 'DUR';
            app.DUREditField = uieditfield(app.BasnTab, 'numeric');
            app.DUREditField.ValueChangedFcn = createCallbackFcn(app, @DUREditFieldValueChanged, true);
            app.GpsTab = uitab(app.TabGroup);
            app.GpsTab.Title = 'Gps';
            app.Image_3 = uiimage(app.GpsTab);
            app.Image_3.ImageSource = 'rrr.png';
            app.Image2_2 = uiimage(app.GpsTab);
            app.Image2_2.ImageSource = 'Screenshot_2.png';
            app.EnlemEditFieldLabel = uilabel(app.GpsTab);
            app.EnlemEditFieldLabel.Text = 'Enlem';
            app.EnlemEditField = uieditfield(app.GpsTab, 'numeric');
            app.EnlemEditField.ValueChangedFcn = createCallbackFcn(app, @EnlemEditFieldValueChanged, true);
            app.AYRINTILIVERILERTextArea_2 = uitextarea(app.GpsTab);
            app.AYRINTILIVERILERTextArea_2.Value = {'AYRINTILI VERILER'};
            app.BoylamEditFieldLabel = uilabel(app.GpsTab);
            app.BoylamEditFieldLabel.Text = 'Boylam';
            app.BoylamEditField = uieditfield(app.GpsTab, 'numeric');
            app.BoylamEditField.ValueChangedFcn = createCallbackFcn(app, @BoylamEditFieldValueChanged, true);
            app.StartButton_3 = uibutton(app.GpsTab, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.DurEditField_2Label = uilabel(app.GpsTab);
            app.DurEditField_2Label.Text = 'Dur';
            app.DurEditField_2 = uieditfield(app.GpsTab, 'numeric');
            app.DurEditField_2.ValueChangedFcn = createCallbackFcn(app, @DurEditField_2ValueChanged, true);
            app.StopButton_2 = uibutton(app.GpsTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.GYROTab = uitab(app.TabGroup);
            app.GYROTab.Title = 'GYRO';
            app.UIAxes2_5 = uiaxes(app.GYROTab);
            title(app.UIAxes2_5, 'Hız vs Zaman')
            xlabel(app.UIAxes2_5, 'Zaman')
            ylabel(app.UIAxes2_5, 'X ekseni')
            app.UIAxes2_6 = uiaxes(app.GYROTab);
            title(app.UIAxes2_6, 'Hız vs Zaman')
            xlabel(app.UIAxes2_6, 'Zaman')
            ylabel(app.UIAxes2_6, 'X ekseni')
            app.UIAxes2_7 = uiaxes(app.GYROTab);
            title(app.UIAxes2_7, 'Hız vs Zaman')
            xlabel(app.UIAxes2_7, 'Zaman')
            ylabel(app.UIAxes2_7, 'X ekseni')
            app.Image_4 = uiimage(app.GYROTab);
            app.Image_4.ImageSource = 'rrr.png';
            app.XEditFieldLabel = uilabel(app.GYROTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GYROTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.AYRINTILIVERILERTextArea_3 = uitextarea(app.GYROTab);
            app.AYRINTILIVERILERTextArea_3.Value = {'AYRINTILI VERILER'};
            app.YEditFieldLabel = uilabel(app.GYROTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GYROTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.GYROTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GYROTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.StartButton_2 = uibutton(app.GYROTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.DurEditFieldLabel = uilabel(app.GYROTab);
            app.DurEditFieldLabel.Text = 'Dur';
            app.DurEditField = uieditfield(app.GYROTab, 'numeric');
            app.DurEditField.ValueChangedFcn = createCallbackFcn(app, @DurEditFieldValueChanged, true);
            app.StopButton_3 = uibutton(app.GYROTab, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = algoritma

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end