classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        UCUSBENZETIMTab           matlab.ui.container.Tab
        ACEditField               matlab.ui.control.NumericEditField
        ACEditFieldLabel          matlab.ui.control.Label
        BILESKEHIZEditFieldLabel  matlab.ui.control.Label
        BILESKEHIZEditField       matlab.ui.control.NumericEditField
        XEKSENIHIZEditField       matlab.ui.control.NumericEditField
        XEKSENIHIZEditFieldLabel  matlab.ui.control.Label
        ZEKSENIHIZEditField       matlab.ui.control.NumericEditField
        ZEKSENIHIZEditFieldLabel  matlab.ui.control.Label
        XKONUMEditField           matlab.ui.control.NumericEditField
        XKONUMEditFieldLabel      matlab.ui.control.Label
        ZKONUMEditField           matlab.ui.control.NumericEditField
        ZKONUMEditFieldLabel      matlab.ui.control.Label
        ZAMANEditField            matlab.ui.control.NumericEditField
        ZAMANEditFieldLabel       matlab.ui.control.Label
        DUREditField              matlab.ui.control.NumericEditField
        DUREditFieldLabel         matlab.ui.control.Label
        STOPButton                matlab.ui.control.Button
        STARTButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            clc;
            mami=importdata('runfg.bat');
g=9.801; %yerçekimi ivmesi
%zaman aralığ 0.01 saniye olması lazım
%il konum [0 0 0];
x_ilk=0; %ilk konum
z_ilk=0;%ilk konum
v=100;
a=70;% uçuşi yola açısı;
m=2.5;%ağırlık 
t_ucus=(2*v*sind(a))/g;
t_cikis=t_ucus/2;
for t=0:0.1:t_ucus
time=0:0.01:t;
vx=v*cosd(a);
% max_irtifa=(v*sind(a)^2)/(2*g);
% max_uzaklik=v*cosd(a).*t_ucus;
x_konum=vx.*time;
z_konum=(v*sind(a).*time) - (1/2).*g.*(time.^2);
vz=v*sind(a)-g*t;
v_bileske=sqrt(vx^2+vz^2);
% plot(x_konum,z_konum);
[maxx,idx] = max(x_konum);
[maxz,idz] = max(z_konum);
plot(app.UIAxes,x_konum,z_konum,x_konum(idx),z_konum(idx),'pr');
  set(app.UIAxes,'xLim',[0 1000]);
  set(app.UIAxes,'yLim',[0 600]);
legend(sprintf('Max İrtifa = %0.3f',maxz),sprintf('Maximum Uzaklık = %0.3f',maxx));
% axis([xlim min(x_konum) max(x_konum)+1]);
trxt=('Max Uzaklık');
txt=('Maximum İrtifa \uparrow');
 text(app.UIAxes,x_konum(idx),z_konum(idx),trxt,'VerticalAlignment','bottom');
 text(app.UIAxes,x_konum(idz),maxz,txt,'HorizontalAlignment','right');
 app.ZAMANEditField.Value=t;
 app.ZKONUMEditField.Value=z_konum(end);
 app.XKONUMEditField.Value=x_konum(end);
 app.BILESKEHIZEditField.Value=v_bileske;
 app.ZEKSENIHIZEditField.Value=vz;
 app.XEKSENIHIZEditField.Value=vx;
 app.ACEditField.Value=atand(vz/vx);
 if  app.DUREditField.Value==1;
     break
 end
 drawnow;
end


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
             app.DUREditField.Value=1;
        end

        % Value changed function: BILESKEHIZEditField
        function BILESKEHIZEditFieldValueChanged(app, event)
            value = app.BILESKEHIZEditField.Value;
            
        end

        % Value changed function: DUREditField
        function DUREditFieldValueChanged(app, event)
            value = app.DUREditField.Value;
            
        end

        % Value changed function: ZAMANEditField
        function ZAMANEditFieldValueChanged(app, event)
            value = app.ZAMANEditField.Value;
            
        end

        % Value changed function: ZKONUMEditField
        function ZKONUMEditFieldValueChanged(app, event)
            value = app.ZKONUMEditField.Value;
            
        end

        % Value changed function: XKONUMEditField
        function XKONUMEditFieldValueChanged(app, event)
            value = app.XKONUMEditField.Value;
            
        end

        % Value changed function: ZEKSENIHIZEditField
        function ZEKSENIHIZEditFieldValueChanged(app, event)
            value = app.ZEKSENIHIZEditField.Value;
            
        end

        % Value changed function: XEKSENIHIZEditField
        function XEKSENIHIZEditFieldValueChanged(app, event)
            value = app.XEKSENIHIZEditField.Value;
            
        end

        % Callback function
        function STARTButton_2Pushed(app, event)


            clc;
clear;
clear all;
close all;
ax = axes('XLim',[-4 400],'YLim',[-4 400],'ZLim',[-4 400]);
view(3); grid on; axis equal;
 %axis off;
% Create objects to group
[cylx,cyly,cylz]=cylinder(.4);
[conx,cony,conz]=cylinder([1,0]);
[mx,my,mz]=cylinder([0,0.1]);
% [x,y,z]=sphere(50);
% h(1)=surface(x,y,z-1);
h(2)=surface(cylx,cyly,cylz*2+1); %orta gövde
h(3)=surface(conx/2-0.01,cony/2-0.01,conz+3); %baş kısımı
h(4)=surface(my/2,mx/4+0.4,mz/1.5+0.5,'FaceColor','red');
h(5)=surface(my/2,mx/4-0.4,mz/1.5+0.5,'FaceColor','red');
h(6)=surface(my/2+0.4,mx/4,mz/1.5+0.5,'FaceColor','red');
h(7)=surface(my/2-0.4,mx/4,mz/1.5+0.5,'FaceColor','red');
set(h(2:7),'Clipping','off');
t = hgtransform('Parent',ax);
set(h(2:7),'Parent',t);


g=9.801; %yerçekimi ivmesi
%zaman aralığ 0.01 saniye olması lazım
%il konum [0 0 0];
x_ilk=0; %ilk konum
z_ilk=0;%ilk konum
v=100;
a=70;% uçuşi yola açısı;
m=2.5;%ağırlık 
t_ucus=(2*v*sind(a))/g;
t_cikis=t_ucus/2;
for tt=0:0.1:t_ucus
time=0:0.01:tt;
vx=v*cosd(a);
% max_irtifa=(v*sind(a)^2)/(2*g);
% max_uzaklik=v*cosd(a).*t_ucus;
x_konum=vx.*time;
z_konum=(v*sind(a).*time) - (1/2).*g.*(time.^2);
vz=v*sind(a)-g*tt;
v_bileske=sqrt(vx^2+vz^2);
% plot(x_konum,z_konum);
[maxx,idx] = max(x_konum);
[maxz,idz] = max(z_konum);
% plot(app.UIAxes,x_konum,z_konum,x_konum(idx),z_konum(idx),'pr');
%   set(app.UIAxes,'xLim',[0 1000]);
%   set(app.UIAxes,'yLim',[0 600]);
% legend(sprintf('Max İrtifa = %0.3f',maxz),sprintf('Maximum Uzaklık = %0.3f',maxx));
% % axis([xlim min(x_konum) max(x_konum)+1]);
% trxt=('Max Uzaklık');
% txt=('Maximum İrtifa \uparrow');
%  text(app.UIAxes,x_konum(idx),z_konum(idx),trxt,'VerticalAlignment','bottom');
%  text(app.UIAxes,x_konum(idz),maxz,txt,'HorizontalAlignment','right');
 
 
    Sxy = makehgtform('zrotate',z_konum(end));
    % Concatenate the transforms and
    % set the transform Matrix property
    set(t,'Matrix',Sxy);
    drawnow;
end 


        end

        % Callback function
        function STARTButton_3Pushed(app, event)
                        clc;
g=9.801; %yerçekimi ivmesi
%zaman aralığ 0.01 saniye olması lazım
%il konum [0 0 0];
x_ilk=0; %ilk konum
z_ilk=0;%ilk konum
v=100;
a=70;% uçuşi yola açısı;
m=2.5;%ağırlık 
t_ucus=(2*v*sind(a))/g;
t_cikis=t_ucus/2;
for t=0:0.1:t_ucus
time=0:0.01:t;
vx=v*cosd(a);
% max_irtifa=(v*sind(a)^2)/(2*g);
% max_uzaklik=v*cosd(a).*t_ucus;
x_konum=vx.*time;
z_konum=(v*sind(a).*time) - (1/2).*g.*(time.^2);
vz=v*sind(a)-g*t;
dikey_hiz=v*sind(a)-g.*time;
v_bileske=sqrt(vx^2+vz^2);
% plot(x_konum,z_konum);
[maxx,idx] = max(x_konum);
[maxz,idz] = max(z_konum);
plot(app.UIAxes3,time,z_konum,time(idz),z_konum(idz),'pr');
plot(app.UIAxes4,time,dikey_hiz);
%   set(app.UIAxes,'xLim',[0 1000]);
%   set(app.UIAxes,'yLim',[0 600]);
% legend(sprintf('Max İrtifa = %0.3f',maxz),sprintf('Maximum Uzaklık = %0.3f',maxx));
% % axis([xlim min(x_konum) max(x_konum)+1]);
% trxt=('Max Uzaklık');
% % txt=('Maximum İrtifa \uparrow');
%  text(app.UIAxes,x_konum(idx),z_konum(idx),trxt,'VerticalAlignment','bottom');
% %  text(app.UIAxes,x_konum(idz),maxz,txt,'HorizontalAlignment','right');
%  app.ZAMANEditField.Value=t;
%  app.ZKONUMEditField.Value=z_konum(end);
%  app.XKONUMEditField.Value=x_konum(end);
%  app.BILESKEHIZEditField.Value=v_bileske;
%  app.ZEKSENIHIZEditField.Value=vz;
%  app.XEKSENIHIZEditField.Value=vx;
 if  app.ACEditField.Value==1;
     break
 end
 drawnow;
end
        end

        % Callback function
        function ButtonPushed(app, event)
                clc;
g=9.801; %yerçekimi ivmesi
%zaman aralığ 0.01 saniye olması lazım
%il konum [0 0 0];
x_ilk=0; %ilk konum
z_ilk=0;%ilk konum
v=100;
a=70;% uçuşi yola açısı;
m=2.5;%ağırlık 
t_ucus=(2*v*sind(a))/g;
t_cikis=t_ucus/2;
for t=0:0.1:t_ucus
time=0:0.01:t;
vx=v*cosd(a);
% max_irtifa=(v*sind(a)^2)/(2*g);
% max_uzaklik=v*cosd(a).*t_ucus;
x_konum=vx.*time;
z_konum=(v*sind(a).*time) - (1/2).*g.*(time.^2);
vz=v*sind(a)-g*t;
dikey_hiz=v*sind(a)-g.*time;
v_bileske=sqrt(vx^2+vz^2);
% plot(x_konum,z_konum);
[maxx,idx] = max(x_konum);
[maxz,idz] = max(z_konum);
plot(app.UIAxes3,time,z_konum,time(idz),z_konum(idz),'pr');
plot(app.UIAxes4,time,dikey_hiz);
%   set(app.UIAxes,'xLim',[0 1000]);
%   set(app.UIAxes,'yLim',[0 600]);
% legend(sprintf('Max İrtifa = %0.3f',maxz),sprintf('Maximum Uzaklık = %0.3f',maxx));
% % axis([xlim min(x_konum) max(x_konum)+1]);
% trxt=('Max Uzaklık');
% % txt=('Maximum İrtifa \uparrow');
%  text(app.UIAxes,x_konum(idx),z_konum(idx),trxt,'VerticalAlignment','bottom');
% %  text(app.UIAxes,x_konum(idz),maxz,txt,'HorizontalAlignment','right');
%  app.ZAMANEditField.Value=t;
%  app.ZKONUMEditField.Value=z_konum(end);
%  app.XKONUMEditField.Value=x_konum(end);
%  app.BILESKEHIZEditField.Value=v_bileske;
%  app.ZEKSENIHIZEditField.Value=vz;
%  app.XEKSENIHIZEditField.Value=vx;
 if  app.ACEditField.Value==1;
     break
 end
 drawnow;
end
        end

        % Value changed function: ACEditField
        function ACEditFieldValueChanged(app, event)
            value = app.ACEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UCUSBENZETIMTab = uitab(app.TabGroup);
            app.UCUSBENZETIMTab.Title = 'UCUS BENZETIM';
            app.UIAxes = uiaxes(app.UCUSBENZETIMTab);
            title(app.UIAxes, 'UÇUŞ BENZETİM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Z')
            app.STARTButton = uibutton(app.UCUSBENZETIMTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UCUSBENZETIMTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DUREditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.DUREditFieldLabel.Text = 'DUR';
            app.DUREditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.DUREditField.ValueChangedFcn = createCallbackFcn(app, @DUREditFieldValueChanged, true);
            app.ZAMANEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.ZAMANEditFieldLabel.Text = 'ZAMAN';
            app.ZAMANEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.ZAMANEditField.ValueChangedFcn = createCallbackFcn(app, @ZAMANEditFieldValueChanged, true);
            app.ZKONUMEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.ZKONUMEditFieldLabel.Text = 'Z KONUM';
            app.ZKONUMEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.ZKONUMEditField.ValueChangedFcn = createCallbackFcn(app, @ZKONUMEditFieldValueChanged, true);
            app.XKONUMEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.XKONUMEditFieldLabel.Text = 'X KONUM';
            app.XKONUMEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.XKONUMEditField.ValueChangedFcn = createCallbackFcn(app, @XKONUMEditFieldValueChanged, true);
            app.ZEKSENIHIZEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.ZEKSENIHIZEditFieldLabel.Text = 'Z EKSENI HIZ';
            app.ZEKSENIHIZEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.ZEKSENIHIZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEKSENIHIZEditFieldValueChanged, true);
            app.XEKSENIHIZEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.XEKSENIHIZEditFieldLabel.Text = 'X EKSENI HIZ';
            app.XEKSENIHIZEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.XEKSENIHIZEditField.ValueChangedFcn = createCallbackFcn(app, @XEKSENIHIZEditFieldValueChanged, true);
            app.BILESKEHIZEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.BILESKEHIZEditField.ValueChangedFcn = createCallbackFcn(app, @BILESKEHIZEditFieldValueChanged, true);
            app.BILESKEHIZEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.BILESKEHIZEditFieldLabel.Text = 'BILESKE HIZ';
            app.ACEditFieldLabel = uilabel(app.UCUSBENZETIMTab);
            app.ACEditFieldLabel.Text = 'ACİ';
            app.ACEditField = uieditfield(app.UCUSBENZETIMTab, 'numeric');
            app.ACEditField.ValueChangedFcn = createCallbackFcn(app, @ACEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end